classdef view_interference < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        GridLayout                    matlab.ui.container.GridLayout
        LeftPanel                     matlab.ui.container.Panel
        axis_size                     matlab.ui.control.NumericEditField
        TickMarksEditFieldLabel       matlab.ui.control.Label
        lattice_spacing               matlab.ui.control.NumericEditField
        LatticespacingEditFieldLabel  matlab.ui.control.Label
        y_translation                 matlab.ui.control.Slider
        ytranslationSliderLabel       matlab.ui.control.Label
        x_translation                 matlab.ui.control.Slider
        xtranslationSliderLabel       matlab.ui.control.Label
        misorientation                matlab.ui.control.Knob
        MisorientationAngleKnobLabel  matlab.ui.control.Label
        RightPanel                    matlab.ui.container.Panel
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        X % x-indices
        Y % y-indices
        view_angle
        camera_position
        tick_marks

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.lattice_spacing.Value=1;
            app.tick_marks = app.axis_size.Value/1;
            app.axis_size.Value=50;
            app.misorientation.Value = 5.5;
            tick_size=app.lattice_spacing.Value/4;
            [app.X,app.Y]=get_plane_indices(app.lattice_spacing.Value, tick_size, app.axis_size.Value);
%             app.UIAxes
%             title(app.UIAxes, 'Interference of Rotated/Skewed Hexagonal Lattice Superimposed over Fixed Lattice')
%             xlabel(app.UIAxes, 'X')
%             ylabel(app.UIAxes, 'Superimposed distance to nearest vertex')
            zlabel(app.UIAxes, 'Superimposed distance to nearest vertex');
            app.UpdateButtonPushed;
          
        end

        % Callback function
        function UpdateButtonPushed(app, event)
            app.camera_position=app.UIAxes.CameraPosition;
            app.view_angle=app.UIAxes.View;
            metalattice = get_superlattice(app.misorientation.Value, app.lattice_spacing.Value, app.x_translation.Value, app.y_translation.Value, app.X, app.Y);
            plot_superlattice_interference(app.UIAxes, app.X, app.Y, metalattice);
            zlim(app.UIAxes, [-1,1]);
%             set(app.UIAxes, view=app.view_angle);
%             set(app.UIAxes, CameraPosition=app.camera_position);
    
            
        end

        % Value changing function: x_translation
        function x_translationValueChanged(app, event)
            value = app.x_translation.Value;
            app.UpdateButtonPushed;
%             UpdateButtonPushed(app);
        end

        % Value changing function: y_translation
        function y_translationValueChanged(app, event)
            value = app.y_translation.Value;
            app.UpdateButtonPushed;
%             app.UpdateButton;
        end

        % Value changed function: lattice_spacing
        function lattice_spacingValueChanged(app, event)
            value = app.lattice_spacing.Value;
            app.UpdateButtonPushed;
          
        end

        % Value changing function: misorientation
        function misorientationValueChanging(app, event)
            changingValue = event.Value;
            app.UpdateButtonPushed;
            
        end

        % Value changed function: axis_size
        function axis_sizeValueChanged(app, event)
            value = app.axis_size.Value;
            
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {470, 470};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {197, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.MisorientationAngleKnobLabel = uilabel(app.LeftPanel);
            app.MisorientationAngleKnobLabel.Text = 'Misorientation Angle';
            app.misorientation = uiknob(app.LeftPanel, 'continuous');
            app.misorientation.Limits = [0 30];
            app.misorientation.MajorTicks = [0 5 10 15 20 25 30];
            app.misorientation.MajorTickLabels = {'0', '5', '10', '15', '20', '25', '30'};
            app.misorientation.ValueChangingFcn = createCallbackFcn(app, @misorientationValueChanging, true);
            app.xtranslationSliderLabel = uilabel(app.LeftPanel);
            app.xtranslationSliderLabel.Text = 'x-translation';
            app.x_translation = uislider(app.LeftPanel);
            app.x_translation.Limits = [0 7];
            app.x_translation.ValueChangingFcn = createCallbackFcn(app, @x_translationValueChanged, true);
            app.ytranslationSliderLabel = uilabel(app.LeftPanel);
            app.ytranslationSliderLabel.Text = 'y-translation';
            app.y_translation = uislider(app.LeftPanel);
            app.y_translation.Limits = [0 7];
            app.y_translation.ValueChangingFcn = createCallbackFcn(app, @y_translationValueChanged, true);
            app.LatticespacingEditFieldLabel = uilabel(app.LeftPanel);
            app.LatticespacingEditFieldLabel.Text = 'Lattice spacing';
            app.lattice_spacing = uieditfield(app.LeftPanel, 'numeric');
            app.lattice_spacing.ValueChangedFcn = createCallbackFcn(app, @lattice_spacingValueChanged, true);
            app.TickMarksEditFieldLabel = uilabel(app.LeftPanel);
            app.TickMarksEditFieldLabel.Text = 'Tick Marks';
            app.axis_size = uieditfield(app.LeftPanel, 'numeric');
            app.axis_size.ValueChangedFcn = createCallbackFcn(app, @axis_sizeValueChanged, true);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Interference of Rotated/Skewed Hexagonal Lattice Superimposed over Fixed Lattice')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = view_interference

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end